classdef Bapp < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                     matlab.ui.Figure
        FilamentdurchmesserLabel_4   matlab.ui.control.Label
        FilamentdurchmesserLabel_3   matlab.ui.control.Label
        travel_opti                  matlab.ui.control.NumericEditField
        inner_wall                   matlab.ui.control.NumericEditField
        direction_down               matlab.ui.control.NumericEditField
        ShowClustersCheckBox         matlab.ui.control.CheckBox
        ClearAllButton               matlab.ui.control.Button
        RetractionDistaceLabel_9     matlab.ui.control.Label
        FilamentdurchmesserLabel_2   matlab.ui.control.Label
        RetractionDistaceLabel_8     matlab.ui.control.Label
        RetractionDistaceLabel_7     matlab.ui.control.Label
        RetractionDistaceLabel_6     matlab.ui.control.Label
        RetractionDistaceLabel_5     matlab.ui.control.Label
        OpenButton                   matlab.ui.control.Button
        paramtest_info               matlab.ui.control.TextArea
        max_cluster                  matlab.ui.control.NumericEditField
        DbScanRadiusLabel_4          matlab.ui.control.Label
        min_cluster                  matlab.ui.control.NumericEditField
        DbScanRadiusLabel_3          matlab.ui.control.Label
        InfillBorderDropDownLabel_2  matlab.ui.control.Label
        gfunction                    matlab.ui.control.TextArea
        travel_speed                 matlab.ui.control.NumericEditField
        RetractionDistaceLabel_4     matlab.ui.control.Label
        print_speed                  matlab.ui.control.NumericEditField
        RetractionDistaceLabel_3     matlab.ui.control.Label
        retraction_speed             matlab.ui.control.NumericEditField
        RetractionDistaceLabel_2     matlab.ui.control.Label
        retraction_distance          matlab.ui.control.NumericEditField
        RetractionDistanceLabel      matlab.ui.control.Label
        filepath                     matlab.ui.control.TextArea
        TestParametersButton         matlab.ui.control.Button
        point_density_factor         matlab.ui.control.NumericEditField
        DbScanRadiusLabel_2          matlab.ui.control.Label
        db_radius                    matlab.ui.control.NumericEditField
        DbScanRadiusLabel            matlab.ui.control.Label
        meshSize                     matlab.ui.control.NumericEditField
        MeshgreLabel                 matlab.ui.control.Label
        min_point                    matlab.ui.control.NumericEditField
        MindestpunktmengezurClusterbildungLabel  matlab.ui.control.Label
        Infill_Border                matlab.ui.control.DropDown
        InfillBorderDropDownLabel    matlab.ui.control.Label
        alpha                        matlab.ui.control.NumericEditField
        BastelfaktorLabel            matlab.ui.control.Label
        filament_diameter            matlab.ui.control.NumericEditField
        FilamentdurchmesserLabel     matlab.ui.control.Label
        nom_w                        matlab.ui.control.NumericEditField
        NominelleBreiteLabel         matlab.ui.control.Label
        agza                         matlab.ui.control.NumericEditField
        ZellgreLabel                 matlab.ui.control.Label
        RunProgrammButton            matlab.ui.control.Button
        nom_h                        matlab.ui.control.NumericEditField
        NominalHeightLabel           matlab.ui.control.Label
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: RunProgrammButton
        function RunProgrammButtonPushed(app, event)
            disp(app.nom_h.Value)

            dbstop if caught error 

            print_speedf = app.print_speed;
            travel_speedf =app.travel_speed.Value;
            retraction_distancef = app.retraction_distance.Value;
            retraction_speedf = app.retraction_speed.Value;
            nom_hf = app.nom_h.Value;
            nom_wf = app.nom_w.Value;
            alphaf = app.alpha.Value;
            filament_diameterf = app.filament_diameter.Value;
            min_pointf = app.min_point.Value;
            agzf = app.agza.Value;
            db_radiusf = app.db_radius.Value;
            point_density_factorf = app.point_density_factor.Value;
            meshSizef = app.meshSize.Value;
            filepathf = app.filepath.Value{1,1};
            gfunctionf = app.gfunction.Value{1,1};
            travel_optif = app.travel_opti.Value;
            direction_downf = app.direction_down.Value;
            inner_wallf = app.inner_wall.Value;
            
   balu_func_v0_2(print_speedf,travel_speedf,retraction_distancef,retraction_speedf,nom_hf,nom_wf,alphaf,filament_diameterf,min_pointf,agzf,db_radiusf,point_density_factorf,meshSizef,filepathf,gfunctionf,travel_optif,direction_downf,inner_wallf);
            

        end

        % Button pushed function: TestParametersButton
        function TestParametersButtonPushed(app, event)
           
            app.paramtest_info.Value = test_params(app.gfunction.Value, ...
                    app.agza.Value, ...
                    app.nom_h.Value, ...
                    app.min_point.Value, ...
                    app.meshSize.Value, ...
                    app.db_radius.Value, ...
                    app.min_cluster.Value, ...
                    app.max_cluster.Value, ...
                    app.ShowClustersCheckBox.Value);
        end

        % Callback function
        function TestaButtonPushed(app, event)
            disp(app.ShowClustersCheckBox.Value);
        end

        % Button pushed function: OpenButton
        function OpenButtonPushed(app, event)
            [p_name,p_path] = uigetfile('*.gcode');

            if p_name ~= 0
                app.filepath.Value = append(p_path,p_name);
            else
                app.filepath.Value = 'You accidentally pressed Cancel. :(';
            end
        end

        % Button pushed function: ClearAllButton
        function ClearAllButtonPushed(app, event)
            clc
            clear
            close

        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.NominalHeightLabel = uilabel(app.UIFigure);
            app.NominalHeightLabel.Text = 'Nominal Height';
            app.nom_h = uieditfield(app.UIFigure, 'numeric');
            app.nom_h.Tag = 'nom_h';
            app.nom_h.Value = 0.1;
            app.RunProgrammButton = uibutton(app.UIFigure, 'push');
            app.RunProgrammButton.ButtonPushedFcn = createCallbackFcn(app, @RunProgrammButtonPushed, true);
            app.RunProgrammButton.Text = 'Run-Programm';
            app.ZellgreLabel = uilabel(app.UIFigure);
            app.ZellgreLabel.Text = 'Cell-Size';
            app.agza = uieditfield(app.UIFigure, 'numeric');
            app.agza.Tag = 'nom_h';
            app.agza.Value = 5;
            app.NominelleBreiteLabel = uilabel(app.UIFigure);
            app.NominelleBreiteLabel.Text = 'Nominell Width';
            app.nom_w = uieditfield(app.UIFigure, 'numeric');
            app.nom_w.Tag = 'nom_h';
            app.nom_w.Value = 0.15;
            app.FilamentdurchmesserLabel = uilabel(app.UIFigure);
            app.FilamentdurchmesserLabel.Text = 'Filament Diameter';
            app.filament_diameter = uieditfield(app.UIFigure, 'numeric');
            app.filament_diameter.Tag = 'nom_h';
            app.filament_diameter.Value = 1.75;
            app.BastelfaktorLabel = uilabel(app.UIFigure);
            app.BastelfaktorLabel.Text = 'alpha-Factor';
            app.alpha = uieditfield(app.UIFigure, 'numeric');
            app.alpha.Tag = 'nom_h';
            app.alpha.Value = 1;
            app.InfillBorderDropDownLabel = uilabel(app.UIFigure);
            app.InfillBorderDropDownLabel.Text = 'Infill-Border';
            app.Infill_Border = uidropdown(app.UIFigure);
            app.Infill_Border.Items = {'Inner-Wall', 'Outer-Wall'};
            app.Infill_Border.Value = 'Outer-Wall';
            app.MindestpunktmengezurClusterbildungLabel = uilabel(app.UIFigure);
            app.MindestpunktmengezurClusterbildungLabel.Text = 'minimal points to consider a Cluster';
            app.min_point = uieditfield(app.UIFigure, 'numeric');
            app.min_point.Tag = 'nom_h';
            app.min_point.Value = 3;
            app.MeshgreLabel = uilabel(app.UIFigure);
            app.MeshgreLabel.Text = 'DBSCAN-Resolution';
            app.meshSize = uieditfield(app.UIFigure, 'numeric');
            app.meshSize.Tag = 'nom_h';
            app.meshSize.Value = 35;
            app.DbScanRadiusLabel = uilabel(app.UIFigure);
            app.DbScanRadiusLabel.Text = 'DbScan-Radius';
            app.db_radius = uieditfield(app.UIFigure, 'numeric');
            app.db_radius.Tag = 'nom_h';
            app.db_radius.Value = 0.3;
            app.DbScanRadiusLabel_2 = uilabel(app.UIFigure);
            app.DbScanRadiusLabel_2.Text = 'Pointdensity-Factor [1/mm^2]';
            app.point_density_factor = uieditfield(app.UIFigure, 'numeric');
            app.point_density_factor.Tag = 'nom_h';
            app.point_density_factor.Value = 15;
            app.TestParametersButton = uibutton(app.UIFigure, 'push');
            app.TestParametersButton.ButtonPushedFcn = createCallbackFcn(app, @TestParametersButtonPushed, true);
            app.TestParametersButton.Text = 'Test Parameters';
            app.filepath = uitextarea(app.UIFigure);
            app.filepath.Editable = 'off';
            app.filepath.Value = {'C:\Users\marvi\Desktop\ThesisNeu\Coding\G-code Files to parse\CE3_idd_smarti_v2.gcode'};
            app.RetractionDistanceLabel = uilabel(app.UIFigure);
            app.RetractionDistanceLabel.Text = 'Retraction-Distance';
            app.retraction_distance = uieditfield(app.UIFigure, 'numeric');
            app.retraction_distance.Tag = 'nom_h';
            app.retraction_distance.Value = 5;
            app.RetractionDistaceLabel_2 = uilabel(app.UIFigure);
            app.RetractionDistaceLabel_2.Text = 'Retraction-Speed';
            app.retraction_speed = uieditfield(app.UIFigure, 'numeric');
            app.retraction_speed.Tag = 'nom_h';
            app.retraction_speed.Value = 2700;
            app.RetractionDistaceLabel_3 = uilabel(app.UIFigure);
            app.RetractionDistaceLabel_3.Text = 'Print-Speed';
            app.print_speed = uieditfield(app.UIFigure, 'numeric');
            app.print_speed.Tag = 'nom_h';
            app.print_speed.Value = 2000;
            app.RetractionDistaceLabel_4 = uilabel(app.UIFigure);
            app.RetractionDistaceLabel_4.Text = 'Travel-Speed';
            app.travel_speed = uieditfield(app.UIFigure, 'numeric');
            app.travel_speed.Tag = 'nom_h';
            app.travel_speed.Value = 4000;
            app.gfunction = uitextarea(app.UIFigure);
            app.gfunction.Value = {'@(x,y)sin(x.*2.*pi/agz).*cos(y.*2.*pi/agz)+sin(y.*2*pi/agz).*cos(2.*z_step.*pi/agz)+sin(2.*z_step.*pi/agz).*cos(2.*x.*pi/agz)'; ''};
            app.InfillBorderDropDownLabel_2 = uilabel(app.UIFigure);
            app.InfillBorderDropDownLabel_2.Text = 'Formula (respect FORMAT!)';
            app.DbScanRadiusLabel_3 = uilabel(app.UIFigure);
            app.DbScanRadiusLabel_3.Text = 'minimal expected Cluster';
            app.min_cluster = uieditfield(app.UIFigure, 'numeric');
            app.min_cluster.Tag = 'nom_h';
            app.min_cluster.Value = 2;
            app.DbScanRadiusLabel_4 = uilabel(app.UIFigure);
            app.DbScanRadiusLabel_4.Text = 'maximum expected Cluster';
            app.max_cluster = uieditfield(app.UIFigure, 'numeric');
            app.max_cluster.Tag = 'nom_h';
            app.max_cluster.Value = 10;
            app.paramtest_info = uitextarea(app.UIFigure);
            app.paramtest_info.Editable = 'off';
            app.paramtest_info.Value = {'Result'};
            app.OpenButton = uibutton(app.UIFigure, 'push');
            app.OpenButton.ButtonPushedFcn = createCallbackFcn(app, @OpenButtonPushed, true);
            app.OpenButton.Text = 'Open';
            app.RetractionDistaceLabel_5 = uilabel(app.UIFigure);
            app.RetractionDistaceLabel_5.Text = 'Printing Parameter';
            app.RetractionDistaceLabel_6 = uilabel(app.UIFigure);
            app.RetractionDistaceLabel_6.Text = 'TPMS-Generation-Parameter';
            app.RetractionDistaceLabel_7 = uilabel(app.UIFigure);
            app.RetractionDistaceLabel_7.Text = 'G-Code Filepath ';
            app.RetractionDistaceLabel_8 = uilabel(app.UIFigure);
            app.RetractionDistaceLabel_8.Text = 'TPMS-Generation-Parameter-Test';
            app.FilamentdurchmesserLabel_2 = uilabel(app.UIFigure);
            app.FilamentdurchmesserLabel_2.Text = 'Search Direction';
            app.RetractionDistaceLabel_9 = uilabel(app.UIFigure);
            app.RetractionDistaceLabel_9.Text = 'Parsing-Options';
            app.ClearAllButton = uibutton(app.UIFigure, 'push');
            app.ClearAllButton.ButtonPushedFcn = createCallbackFcn(app, @ClearAllButtonPushed, true);
            app.ClearAllButton.Text = 'Clear All';
            app.ShowClustersCheckBox = uicheckbox(app.UIFigure);
            app.ShowClustersCheckBox.Text = 'Show Clusters?';
            app.direction_down = uieditfield(app.UIFigure, 'numeric');
            app.direction_down.Tag = 'nom_h';
            app.direction_down.Value = 1;
            app.inner_wall = uieditfield(app.UIFigure, 'numeric');
            app.inner_wall.Tag = 'nom_h';
            app.inner_wall.Value = 1;
            app.travel_opti = uieditfield(app.UIFigure, 'numeric');
            app.travel_opti.Tag = 'nom_h';
            app.FilamentdurchmesserLabel_3 = uilabel(app.UIFigure);
            app.FilamentdurchmesserLabel_3.Text = 'Inner-Wall';
            app.FilamentdurchmesserLabel_4 = uilabel(app.UIFigure);
            app.FilamentdurchmesserLabel_4.Text = 'Travel-Optimization';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Bapp

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end